classdef MazeSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button_7           matlab.ui.control.Button
        Button_6           matlab.ui.control.Button
        EditField          matlab.ui.control.NumericEditField
        EditFieldLabel     matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        YEditField         matlab.ui.control.NumericEditField
        YEditFieldLabel    matlab.ui.control.Label
        YEditField_2       matlab.ui.control.NumericEditField
        YEditField_2Label  matlab.ui.control.Label
        XEditField_2       matlab.ui.control.NumericEditField
        XEditField_2Label  matlab.ui.control.Label
        Spinner            matlab.ui.control.Spinner
        Label_3            matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        Label              matlab.ui.control.Label
        XEditField         matlab.ui.control.NumericEditField
        XEditFieldLabel    matlab.ui.control.Label
        Button_5           matlab.ui.control.Button
        Button_4           matlab.ui.control.Button
        Button_3           matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 前回起動時の迷路データを使用して描画したいが、ランダム生成の方が楽？
            % 一回パースしたら、別データから描画する
            figure(app.UIFigure);
            % スタート位置に機体を描画
            body = rectangle(app.UIAxes,"Position",[1-0.7 1-0.7 0.4 0.4],"Curvature",[1 1],'FaceColor','r');
            global Body
            Body = body;

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %createROI(app);
            %Import Maze from Image
            
             [maze_size, walldata,filename]= mazeloader();
             setN(maze_size);

             title(app.UIAxes, filename);
             app.EditField.Value = maze_size;
             app.UIAxes.XLim = [0 maze_size];
             app.UIAxes.YLim = [0 maze_size];

             
             convertmazedata(maze_size,walldata);
             
             DrawMaze(app.UIFigure,app.UIAxes);
             
             pbaspect([1 1 1]);
             %app.UIAxes.UserData = maze_size;
             %maze_size = app.EditField.Value;
            %leftsearch(app.UIAxes,maze_size);
             %h = viscircles(app.UIAxes,[x y],0.3);
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %アルゴリズム実行 % スクリプト名を指定しておく
                % 探索用ファイルの指定
                % 最短走行用のファイルの指定
            maze_size = app.EditField.Value;
            setN(maze_size);
            tic
            ConvertDrawtoW4NN();
            toc
            global Body
            tic
            leftsearch(app.UIAxes,maze_size,Body);
            toc
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            persistent i
            if isempty(i)
                i = 0;
            end
            if i == 0
                pause('off');
                i = 1;
            else
                pause('on');
                i = 0;
            end
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            [maze_size,walldata,filename] = getMazeMatrix();
            setN(maze_size);
            title(app.UIAxes, filename);
            app.EditField.Value = maze_size;
            app.UIAxes.XLim = [0 maze_size];
            app.UIAxes.YLim = [0 maze_size];
            
            convertmazedata(maze_size,walldata);
            
            disp("OKOK");

            
            DrawMaze(app.UIFigure,app.UIAxes);
            
            pbaspect([1 1 1]);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            keepR_raw_column();
                
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % 壁配列のインポート
            tic
            [maze_size,filename] = getR_raw_column();
            setN(maze_size);
            app.EditField.Value = maze_size;
            app.UIAxes.XLim = [0 maze_size];
            app.UIAxes.YLim = [0 maze_size];
            DrawMaze(app.UIFigure,app.UIAxes);
            pbaspect([1 1 1]);
            toc
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Maze')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 32];
            app.UIAxes.YLim = [0 32];
            app.UIAxes.XTick = [0 4 8 12 16 20 24 28 32];
            app.UIAxes.XTickLabel = {'0'; '4'; '8'; '12'; '16'; '20'; '24'; '28'; '32'};
            app.UIAxes.YTick = [0 4 8 12 16 20 24 28 32];
            app.UIAxes.YTickLabel = {'0'; '4'; '8'; '12'; '16'; '20'; '24'; '28'; '32'};
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '画像からインポート';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '保存';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '開始';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '停止';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = 'リセット';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = '左下 X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Value = 6;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'メモ';
            app.TextArea = uitextarea(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '速度';
            app.Spinner = uispinner(app.UIFigure);
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = '右上 X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.Value = 6;
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = '右上 Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.Value = 6;
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = '左下 Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Value = 6;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'ゴール座標';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '探索結果';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '迷路サイズ';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 9;
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = 'テキストからインポート';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = 'インポート';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MazeSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end